classdef openGMtable_v4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        PeriodsPanel                 matlab.ui.container.Panel
        Button_4                     matlab.ui.control.Button
        Button_3                     matlab.ui.control.Button
        PlotPeriodsListBox           matlab.ui.control.ListBox
        PlotPeriodsListBoxLabel      matlab.ui.control.Label
        ListPeriodsListBox           matlab.ui.control.ListBox
        ListPeriodsListBoxLabel      matlab.ui.control.Label
        RButton                      matlab.ui.control.Button
        OpenExcelFileEditFieldLabel  matlab.ui.control.Label
        ed_excel                     matlab.ui.control.EditField
        PlotPanel                    matlab.ui.container.Panel
        ZoomButton                   matlab.ui.control.StateButton
        PlotButton                   matlab.ui.control.Button
        ClearButton                  matlab.ui.control.Button
        PlotTypeButtonGroup          matlab.ui.container.ButtonGroup
        StackedButton                matlab.ui.control.RadioButton
        StandardButton               matlab.ui.control.RadioButton
        StationPanel                 matlab.ui.container.Panel
        PolButton                    matlab.ui.control.Button
        RButton_2                    matlab.ui.control.Button
        Button_2                     matlab.ui.control.Button
        Button                       matlab.ui.control.Button
        PlotStationListBox           matlab.ui.control.ListBox
        PlotStationListBoxLabel      matlab.ui.control.Label
        ListStationListBox           matlab.ui.control.ListBox
        ListStationListBoxLabel      matlab.ui.control.Label
        OpenGRDPanel                 matlab.ui.container.Panel
        BrowseButton_3               matlab.ui.control.Button
        ConvertButton                matlab.ui.control.Button
        ShowButton                   matlab.ui.control.Button
        MATFileEditField             matlab.ui.control.EditField
        MATFileEditFieldLabel        matlab.ui.control.Label
        BrowseButton_2               matlab.ui.control.Button
        grdFileEditField             matlab.ui.control.EditField
        grdFileEditFieldLabel        matlab.ui.control.Label
        OpenButton                   matlab.ui.control.Button
        BrowseButton                 matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
        DeltaOptionPanel             matlab.ui.container.Panel
        ExecuteButton                matlab.ui.control.Button
        YearBeforeDropDownLabel      matlab.ui.control.Label
        YearBeforeDropDown           matlab.ui.control.DropDown
        YearAfterDropDown            matlab.ui.control.DropDown
        YearAfterDropDownLabel       matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Make all Value empty
            app.PlotPeriodsListBox.Items={};
            app.PlotPeriodsListBox.Multiselect='on';
            app.ListPeriodsListBox.Items={};
            app.PlotStationListBox.Items={};
            app.PlotStationListBox.Multiselect='on';
            app.ListStationListBox.Items={};
            app.YearAfterDropDown.Items={};
            app.YearBeforeDropDown.Items={};
            app.UITable.Data={};
            app.UITable.ColumnName={};
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            %%Move selected year to the plot box
            ally=app.ListPeriodsListBox.Items;
            global year tempy
            ploty=app.PlotPeriodsListBox.Items;
            ploty=ploty';
            sely=app.ListPeriodsListBox.Value;
            ploty=[ploty; sely];
            app.PlotPeriodsListBox.Items=ploty;
            %disappearing selected item from Box ListStation to PlotStation
            if isempty(sely) == 0;
            d=find(strcmp(year,sely));
            tempy(d)={''};
            temp2=tempy;
            index=cellfun(@isempty,temp2,'uni',false);
            index=cellfun(@any,index);
            idx=find(index);
            temp2(idx)=[];
            ally=temp2;
            app.ListPeriodsListBox.Items=ally;
            else
            end
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            %Put back selected year to the list periods
            
            ply=app.PlotPeriodsListBox.Value;
            yall=app.PlotPeriodsListBox.Items;
            if isempty(ply)==0;
            e=find(strcmp(yall,ply));
            yall(e)=[];
            app.PlotPeriodsListBox.Items=yall;
            global year tempy;
            [s ss]=size(year);
            r=find(strcmp(year,ply));
            ally=app.ListPeriodsListBox.Items;
            if r == s;
                ally=[ally(1:end),ply];
                tempy(s)=ply;
                app.ListPeriodsListBox.Items=ally;
            elseif r == 1;
                ally=[ply,ally(1:end)];
                tempy(1)=ply;
                app.ListPeriodsListBox.Items=ally;
            else
                tempy(r)=ply;
                temp2=tempy;
                index=cellfun(@isempty,temp2,'uni',false);
                index=cellfun(@any,index);
                idx=find(index);
                temp2(idx)=[];
                ally=temp2;
                app.ListPeriodsListBox.Items=ally;
            end
            else
            end
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            %%Browse xls file with certain template
            [namafile,direktori]=uigetfile({'*.xlsx','*.xls'},'Buka File');
            if isequal(namafile,0);
                disp('User Selected Cancel');
            else
                disp(['User Selected', fullfile(direktori,namafile)]);
                filedirectori=([direktori,namafile]);
                [a,b,c]=fileparts(filedirectori);
                app.ed_excel.Value=filedirectori;
            end
            
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            %%Open the excel file and then auto plot the station with utmx
            %%and utm y, also activated data cursor mode to pick the
            %%station
            gmfile=app.ed_excel.Value;
            %read the table
            t=readtable(gmfile,'ReadVariableNames',true,'VariableNamingRule',"preserve");
            header=t.Properties.VariableNames;
            %make some variable global so it could be used in another
            %function
            global BM tempsta year tempy
            BM=t.STA;
            tempsta=t.STA;
            save gm_table t header BM
            app.UITable.Data=t;
            app.UITable.ColumnName=header;
            year=header(4:end)';
            tempy=year;
            [m n]=size(year);
            %Put the variable and value to the coresponding component
            app.YearAfterDropDown.Items=year;
            app.YearBeforeDropDown.Items=year;
            app.YearAfterDropDown.ItemsData=year;
            app.YearAfterDropDown.ItemsData=year;
            app.YearAfterDropDown.Value=year{2};
            app.YearBeforeDropDown.Value=year{1};
            app.ListStationListBox.Items=BM;
            app.ListStationListBox.Multiselect='on';
            app.ListPeriodsListBox.Items=year;
            app.ListPeriodsListBox.Multiselect='on';
            app.PlotPeriodsListBox.Items={};
            app.PlotStationListBox.Items={};
            utmx=t.UTM_X; utmy=t.UTM_Y;
            z=1000.*ones(size(utmx));
            tt=table2cell(t); tt=tt';
            header2=tt(1,:);
            tt(1:3,:)=[];
            tt=cell2table(tt);
            tt.Properties.VariableNames=header2;
            save gm_table t header BM tt header2 utmx utmy;
            %Plotting the station with utm x and z
            fplot=figure(1);
            sc=scatter3(utmx,utmy,z,"Marker",'diamond',"MarkerFaceColor",'r');
            hold on
            %Enable sort method with depth and make a fake depth
            ax=gca;
            ax.Children;
            ax.SortMethod='Depth';
            view(0,90);
            %Enable the data cursormode
            dcm=datacursormode;
            dcm.Enable='on';
            global cinf;
            dcm.DisplayStyle='window';
            cinf=getCursorInfo(dcm);
            dcm.UpdateFcn = @displayCoordinates;
        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
            %%Browse the .dat surfer file. you could extract surfer grid to
            %%x y z format (.dat)
            [namafile,direktori]=uigetfile({'*.dat'},'Buka File');
            if isequal(namafile,0);
                disp('User Selected Cancel');
            else
                disp(['User Selected', fullfile(direktori,namafile)]);
                filedirectori=([direktori,namafile]);
                [a,b,c]=fileparts(filedirectori);
                app.grdFileEditField.Value=filedirectori;
                app.MATFileEditField.Value=[a,'\',b,'.MAT'];
            end
        end

        % Button pushed function: ConvertButton
        function ConvertButtonPushed(app, event)
            %Convert the surfer grid (.dat) to matlab format (.MAT) and
            %also auto countour the output file (.MAT)
            out=app.MATFileEditField.Value;
            in=app.grdFileEditField.Value;
            gridsurf2mat(in,out);
            filein=load(in);
            load(out);
            xmax=max(filein(:,1));
            xmin=min(filein(:,1));
            ymin=min(filein(:,2));
            ymax=max(filein(:,2));
%             figure(1);
            fplot=get(gcf);
            co=contour(X,Y,Z,10,"-k");
            hold on;
            pc=pcolor(X,Y,Z);
            shading 'interp';
            colormap 'jet';
            xlim([xmin, xmax]);
            ylim([ymin, ymax]);
%             axis(app.UIAxes,"equal")
%             axis(app.UIAxes,"tight")
            hold on
            view(0,90);
            %Enable the data cursor mode
            dcm=datacursormode;
            dcm.Enable='on';
            global cinf;
            dcm.DisplayStyle='window';
            cinf=getCursorInfo(dcm);
            dcm.UpdateFcn = @displayCoordinates;
        end

        % Button pushed function: ShowButton
        function ShowButtonPushed(app, event)
            %%Contour the .MAT file 
            filein=app.MATFileEditField.Value;
            if isempty(filein) ~= 1;
                load(filein);
                xmax=max(X(:,end));
                xmin=min(X(:,1));
                ymin=min(Y(:,1));
                ymax=max(Y(:,end));
%             figure(1);
                fplot=get(gcf);
                co=contour(X,Y,Z,10,"-k");
                hold on
                pc=pcolor(X,Y,Z);
                shading interp ;
                colormap jet;
                xlim([xmin, xmax]);
                ylim([ymin, ymax]);
%             axis(app.UIAxes,"equal")
%             axis(app.UIAxes,"tight")
                hold on
                view(0,90);
                %Enable the data cursor mode
                dcm=datacursormode;
                dcm.Enable='on';
                global cinf;
                dcm.DisplayStyle='window';
                cinf=getCursorInfo(dcm);
                dcm.UpdateFcn = @displayCoordinates;
            else
            end
            
        end

        % Value changed function: YearAfterDropDown
        function YearAfterDropDownValueChanged(app, event)
            %Pick a newest year of the data then substract with the latest
            %one.
            yafter=event.Value;
               year=app.YearAfterDropDown.Items;
               ya=find(strcmp(year,yafter));
               ybefore = app.YearBeforeDropDown.Value;
               yb=find(strcmp(year,ybefore));
        
            if str2double(yafter) <= str2double(ybefore);
                disp('Sorry, the year after must be newer than year before')
                set(app.ExecuteButton,'Enable','off');
            else
                disp('Please click execute button and the result will be new column on the right side')
                set(app.ExecuteButton,'Enable','on');
            end

        end

        % Value changed function: YearBeforeDropDown
        function YearBeforeDropDownValueChanged(app, event)
            %Pick the latest year of the data then substract it with the
            %newest one
            ybefore=event.Value;
               yearb=app.YearBeforeDropDown.Items;
               yb=find(strcmp(yearb,ybefore));
               yafter = app.YearAfterDropDown.Value;
               
            if str2double(ybefore) >= str2double(yafter);
                disp('Sorry, the year before must be older than year after')
                set(app.ExecuteButton,'Enable','off');
            else
                disp('Please click execute button and the result will be new column on the right side')
                set(app.ExecuteButton,'Enable','on');
            end
            
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            %%Execute the substraction then put the result at the most
            %%right column
            load gm_table.mat;
            ybefore=app.YearBeforeDropDown.Value;
            yafter=app.YearAfterDropDown.Value;
            ya=find(strcmp(header,yafter));
            yb=find(strcmp(header,ybefore));
            deltaG=t{:,ya} - t{:,yb};
            subG={[yafter,'-',ybefore]};
            deltaG2=string(subG);
            t.deltaG2=deltaG;
            header=[header,subG];
            app.UITable.ColumnName=header;
            app.UITable.Data=t;
            t.Properties.VariableNames=header;
            year=header(4:end);
            app.ListPeriodsListBox.Items=year;
            tt=table2cell(t); tt=tt';
            header2=tt(1,:);
            tt(1:3,:)=[];           
            tt=cell2table(tt);
            tt.Properties.VariableNames=header2;
            save gm_table t header BM tt header2 utmx utmy;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %Move selected station (select from the table or from the list
            %box) to the plot list.
            allsta=app.ListStationListBox.Items;
            global BM tempsta
            plotsta=app.PlotStationListBox.Items;
            selsta=app.ListStationListBox.Value;
            [a aa]=size(selsta);
            plotsta=[plotsta, selsta];
            plotsta=plotsta';
            app.PlotStationListBox.Items=plotsta;
            %disappearing selected item from Box ListStation to PlotStation
            if isempty(selsta) == 0;
                for i=1:aa;
                    d=find(strcmp(BM,selsta(i)));
                    tempsta(d)={''};
                    temp2=tempsta;
                    index=cellfun(@isempty,temp2,'uni',false);
                    index=cellfun(@any,index);
                    idx=find(index);
                    temp2(idx)=[];
                    allsta=temp2;
                end
                app.ListStationListBox.Items=allsta;
            else
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            %Put back the selected station to the List Station
            plsta=app.PlotStationListBox.Value;
            plall=app.PlotStationListBox.Items;
            if isempty(plsta)==0;
            e=find(strcmp(plall,plsta));
            plall(e)=[];
            app.PlotStationListBox.Items=plall;
            global BM tempsta;
            [s ss]=size(BM);
            r=find(strcmp(BM,plsta));
            allsta=app.ListStationListBox.Items;
            if r == s;
                allsta=[allsta(1:end),plsta];
                tempsta(r)=plsta;
                app.ListStationListBox.Items=allsta;
            elseif r == 1;
                allsta=[plsta,allsta(1:end)];
                tempsta(1)=plsta;
                app.ListStationListBox.Items=allsta;
            else
                tempsta(r)=plsta;
                temp2=tempsta;
                index=cellfun(@isempty,temp2,'uni',false);
                index=cellfun(@any,index);
                idx=find(index);
                temp2(idx)=[];
                allsta=temp2;
                app.ListStationListBox.Items=allsta;
            end
            else
            end
        end

        % Button pushed function: RButton_2
        function RButton_2Pushed(app, event)
            %Reset the value from both station box
            global BM tempsta;
            tempsta=BM;
            app.ListStationListBox.Items=BM;
            app.PlotStationListBox.Items={};
        end

        % Button pushed function: RButton
        function RButtonPushed(app, event)
            %%Reset the periods Boxes
            global year tempy;
            tempy=year;
            app.ListPeriodsListBox.Items=year;
            app.PlotPeriodsListBox.Items={};
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            %%Plot selected data from station plot box and periods plot box
            %%Plot data secara stacked plot (lebih cepat, tetapi label X
            %%tidak dapat diganti tahun, dan terbatas variablenya), juga
            %%memunculkan garis regresi
            ptype=app.StackedButton.Value;
            stafrom=app.PlotStationListBox.Items;
            listcol=app.PlotPeriodsListBox.Items;
            [k kk]=size(stafrom);
            [u uu]=size(listcol);
            load gm_table.mat;
            if ptype==1;
               if uu == 1;
                px1=find(strcmp(header,listcol))-3;
                pt1=tt(px1,:);
                else
                    pxx=[];
                        for i=1:uu;
                        px1=find(strcmp(header,listcol(i)))-3;
                        pt1=tt(px1,:);
                        pxx=[pxx;pt1];
                        end
                end
            
               if kk == 1;
               py1=find(strcmp(header2,stafrom));
               pt2=pxx(:,py1);
                else
                pyy=[];
                    for j=1:kk;
                    py1=find(strcmp(header2,stafrom(j)));
                    pt2=pxx(:,py1);
                    pyy=[pyy, pt2];
                    end
               end        
            [a b]=size(pyy);
            fig=figure(2);
            s=stackedplot(pyy);
            grid on;
            s.Color='r';s.Marker='d'; s.MarkerFaceColor='r'; s.MarkerEdgeColor='k'; s.MarkerSize=6;           
%             xticks([1:a])
%             xticklabels(listcol)
            gs=findobj(fig,'Type','stackedplot');
            ax=flipud(findobj(gs.NodeChildren,'Type','Axes'));
            for i=1:numel(ax);
                x=get(ax(i).Children,'XData');
                y=get(ax(i).Children,'YData');
                coef=polyfit(x,y,1);
                refs=refline(ax(i),coef(1),coef(2));
                refs.LineWidth=1;
                refs.LineStyle='--';     
                refs.Color='k';
            end
            %%Plot secara subplot sehingga dapat diubah x labelnya
            else
               if uu == 1;
               px1=find(strcmp(header,listcol))-3;
               pt1=tt(px1,:);
                else
                    pxx=[]; idp=[];
                    for i=1:uu;
                    px1=find(strcmp(header,listcol(i)))-3;
                    pt1=tt(px1,:);
                    pxx=[pxx;pt1];
                    idp=[idp;px1];
                    end
               end
                    if kk == 1;
                    py1=find(strcmp(header2,stafrom));
                    pt2=pxx(:,py1);
                    else
                    pyy=[];
                    for j=1:kk;
                    py1=find(strcmp(header2,stafrom(j)));
                    pt2=pxx(:,py1);
                    pyy=[pyy, pt2];
                    end
                    end
                    ylab=pyy.Properties.VariableNames;
                    [a b]=size(pyy);
                    q=3; g=1;

                        for p=1:b;
                            if mod(p-1,4) == 0;
                            q=q+1;
                            fig=figure(q);g=1;
                            subplot(4,1,g);
                            ply=pyy.(p);
                            plot([1:a],ply,"Marker","diamond","MarkerFaceColor","r","MarkerEdgeColor","k",...
                                "MarkerSize",7);
                            ylabel(ylab(1,p));
                            xticks([1:a]);
                            xticklabels(listcol);
%                           listcol=listcol'
                            g=g+1;
                            grid on;
                            hold on;
                                else
                                fig=figure(q);
                                subplot(4,1,g);
                                ply=pyy.(p);
                                plot([1:a],ply,"Marker","diamond","MarkerFaceColor","r","MarkerEdgeColor","k",...
                                "MarkerSize",7);
                                ylabel(ylab(1,p));
                                xticks([1:a]);
                                xticklabels(listcol);
%                               listcol=listcol'
                                grid on;
                                hold on;
                                g=g+1
                            end
                        end
            end
            
            figure(1)
            z=1002.*ones(a,1)
            sc=scatter3(utmx(idp),utmy(idp),z,"Marker",'o',"MarkerEdgeColor",'b');
            hold on

        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            %%Clear all the plot boxes
            global BM tempsta;
            tempsta=BM;
            app.ListStationListBox.Items=BM;
            app.PlotStationListBox.Items={};
            global year tempy;
            tempy=year;
            app.ListPeriodListBox.Items=year;
            app.PlotPeriodsListBox.Items={};
        end

        % Button pushed function: BrowseButton_3
        function BrowseButton_3Pushed(app, event)
            %%Browse the .MAT file (convert result from .dat surfer grid
            %%file)
            [namafile,direktori]=uigetfile({'*.MAT'},'Buka File');
            if isequal(namafile,0);
                disp('User Selected Cancel');
            else
                disp(['User Selected', fullfile(direktori,namafile)]);
                filedirectori=([direktori,namafile]);
                [a,b,c]=fileparts(filedirectori);
                app.MATFileEditField.Value=filedirectori;
            end
        end

        % Value changed function: ListStationListBox
        function ListStationListBoxValueChanged(app, event)
            value = app.ListStationListBox.Value;
            global BM;
            c=find(strcmp(BM,value));           
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            global BM year;
            b=event.Indices(1,2);
            a=event.Indices(1,1);
            if b == 1;
            app.ListStationListBox.Value=BM(a);
            elseif b >= 4;
                app.ListStationListBox.Value=BM(a);
                app.ListPeriodsListBox.Value=year(b-3);
            else
            end
        end

        % Button pushed function: PolButton
        function PolButtonPushed(app, event)
           %Use boundary to select the station from the map. Click left
           %mouse button to make the polygon and enter to close the polygon
            
            [x,y]=ginput();
           load gm_table.mat;
           global BM tempsta;
            if isempty(x)==0;
                plot([x;x(1)],[y;y(1)],'-');
                i1=inpolygon(utmx,utmy,x,y);
                idh=find(i1==1);
                nid=length(idh);
                z=1001.*ones(nid,1);
                scatter3(utmx(idh),utmy(idh),z,"Marker",'*',"MarkerFaceColor",'b');
                save stapol idh;
                app.PlotStationListBox.Items=BM(idh);
                else
            end
 
            selsta=app.PlotStationListBox.Items;
            [a aa]=size(BM(idh));
            %disappearing selected item from Box ListStation to PlotStation
%             if isempty(selsta) == 0;
                for i=1:aa;
                    d=find(strcmp(BM,selsta(i)));
                    tempsta(d)={''};
                    temp2=tempsta;
                    index=cellfun(@isempty,temp2,'uni',false);
                    index=cellfun(@any,index);
                    idx=find(index);
                    temp2(idx)=[];
                    allsta=temp2;
                end
                app.ListStationListBox.Items=allsta;
%             else
%             end
        end

        % Value changed function: ZoomButton
        function ZoomButtonValueChanged(app, event)
            %%Enable mouse scroll wheel to zoom in / zoom out
            app.ZoomButton.Value
            if app.ZoomButton.Value == 1;
                view(0,90);
                dcm=datacursormode;
                dcm.Enable='off';
                imgzoompan(figure(1))
            else
                ax=gca;
                ax.Children;
                ax.SortMethod='Depth';
                view(0,90);
                dcm=datacursormode;
                dcm.Enable='on';
                global cinf;
                dcm.DisplayStyle='window';
                cinf=getCursorInfo(dcm);
                dcm.UpdateFcn = @displayCoordinates;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DeltaOptionPanel = uipanel(app.UIFigure);
            app.DeltaOptionPanel.Title = 'Delta Option';
            app.YearAfterDropDownLabel = uilabel(app.DeltaOptionPanel);
            app.YearAfterDropDownLabel.Text = 'Year After';
            app.YearAfterDropDown = uidropdown(app.DeltaOptionPanel);
            app.YearAfterDropDown.ValueChangedFcn = createCallbackFcn(app, @YearAfterDropDownValueChanged, true);
            app.YearBeforeDropDown = uidropdown(app.DeltaOptionPanel);
            app.YearBeforeDropDown.ValueChangedFcn = createCallbackFcn(app, @YearBeforeDropDownValueChanged, true);
            app.YearBeforeDropDownLabel = uilabel(app.DeltaOptionPanel);
            app.YearBeforeDropDownLabel.Text = 'Year Before';
            app.ExecuteButton = uibutton(app.DeltaOptionPanel, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.OpenGRDPanel = uipanel(app.UIFigure);
            app.OpenGRDPanel.Title = 'Open GRD';
            app.grdFileEditFieldLabel = uilabel(app.OpenGRDPanel);
            app.grdFileEditFieldLabel.Text = 'grd File';
            app.grdFileEditField = uieditfield(app.OpenGRDPanel, 'text');
            app.BrowseButton_2 = uibutton(app.OpenGRDPanel, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Text = 'Browse';
            app.MATFileEditFieldLabel = uilabel(app.OpenGRDPanel);
            app.MATFileEditFieldLabel.Text = 'MAT File';
            app.MATFileEditField = uieditfield(app.OpenGRDPanel, 'text');
            app.ShowButton = uibutton(app.OpenGRDPanel, 'push');
            app.ShowButton.ButtonPushedFcn = createCallbackFcn(app, @ShowButtonPushed, true);
            app.ShowButton.Text = 'Show';
            app.ConvertButton = uibutton(app.OpenGRDPanel, 'push');
            app.ConvertButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertButtonPushed, true);
            app.ConvertButton.Text = 'Convert';
            app.BrowseButton_3 = uibutton(app.OpenGRDPanel, 'push');
            app.BrowseButton_3.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_3Pushed, true);
            app.BrowseButton_3.Text = 'Browse';
            app.StationPanel = uipanel(app.UIFigure);
            app.StationPanel.Title = 'Station';
            app.ListStationListBoxLabel = uilabel(app.StationPanel);
            app.ListStationListBoxLabel.Text = 'List Station';
            app.ListStationListBox = uilistbox(app.StationPanel);
            app.ListStationListBox.ValueChangedFcn = createCallbackFcn(app, @ListStationListBoxValueChanged, true);
            app.PlotStationListBoxLabel = uilabel(app.StationPanel);
            app.PlotStationListBoxLabel.Text = 'Plot Station';
            app.PlotStationListBox = uilistbox(app.StationPanel);
            app.Button = uibutton(app.StationPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '>>';
            app.Button_2 = uibutton(app.StationPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '<<';
            app.RButton_2 = uibutton(app.StationPanel, 'push');
            app.RButton_2.ButtonPushedFcn = createCallbackFcn(app, @RButton_2Pushed, true);
            app.RButton_2.Text = 'R';
            app.PolButton = uibutton(app.StationPanel, 'push');
            app.PolButton.ButtonPushedFcn = createCallbackFcn(app, @PolButtonPushed, true);
            app.PolButton.Text = 'Pol';
            app.PlotPanel = uipanel(app.UIFigure);
            app.PlotPanel.Title = 'Plot Panel';
            app.PlotTypeButtonGroup = uibuttongroup(app.PlotPanel);
            app.PlotTypeButtonGroup.Title = 'Plot Type';
            app.StandardButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.StandardButton.Text = 'Standard';
            app.StandardButton.Value = true;
            app.StackedButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.StackedButton.Text = 'Stacked';
            app.ClearButton = uibutton(app.PlotPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.PlotButton = uibutton(app.PlotPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ZoomButton = uibutton(app.PlotPanel, 'state');
            app.ZoomButton.ValueChangedFcn = createCallbackFcn(app, @ZoomButtonValueChanged, true);
            app.ZoomButton.Text = 'Zoom';
            app.ed_excel = uieditfield(app.UIFigure, 'text');
            app.ed_excel.Tag = 'ed_excel';
            app.OpenExcelFileEditFieldLabel = uilabel(app.UIFigure);
            app.OpenExcelFileEditFieldLabel.Text = 'Open Excel File';
            app.PeriodsPanel = uipanel(app.UIFigure);
            app.PeriodsPanel.Title = 'Periods';
            app.RButton = uibutton(app.PeriodsPanel, 'push');
            app.RButton.ButtonPushedFcn = createCallbackFcn(app, @RButtonPushed, true);
            app.RButton.Text = 'R';
            app.ListPeriodsListBoxLabel = uilabel(app.PeriodsPanel);
            app.ListPeriodsListBoxLabel.Text = 'List Periods';
            app.ListPeriodsListBox = uilistbox(app.PeriodsPanel);
            app.PlotPeriodsListBoxLabel = uilabel(app.PeriodsPanel);
            app.PlotPeriodsListBoxLabel.Text = 'Plot Periods';
            app.PlotPeriodsListBox = uilistbox(app.PeriodsPanel);
            app.Button_3 = uibutton(app.PeriodsPanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '>>';
            app.Button_4 = uibutton(app.PeriodsPanel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '<<';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = openGMtable_v4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end